classdef cottonglovepage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ClearImageButton              matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        OutputImageLabel              matlab.ui.control.Label
        uploadimage                   matlab.ui.control.Image
        UploadImageButton             matlab.ui.control.Button
        outputimage                   matlab.ui.control.Image
        MissingFingerDetectionButton  matlab.ui.control.Button
        StainsDetectionButton         matlab.ui.control.Button
        BurnDetectionButton           matlab.ui.control.Button
        CottonGlovePageLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Images (*.jpg, *.png, *.bmp)'}, 'Select an Image');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
               % Read the selected image
                selectedImage = imread(fullfile(path, file));
        
                % Display the image in the Image component
                app.uploadimage.ImageSource = selectedImage;
                
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            mainpage;
            closereq;
        end

        % Button pushed function: ClearImageButton
        function ClearImageButtonPushed(app, event)
          % Create a transparent image
            transparentImage = uint8(zeros(1, 1, 3)); % Creating a 1x1x3 transparent image
            app.uploadimage.ImageSource = transparentImage;  

          % Create a transparent image
            transparentImage = uint8(zeros(1, 1, 3)); % Creating a 1x1x3 transparent image
            app.outputimage.ImageSource = transparentImage;

            uialert(app.UIFigure, 'Upload image & Output image is cleared', 'Clear Image', 'Icon', 'info');
        end

        % Button pushed function: BurnDetectionButton
        function BurnDetectionButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CottonGlovePageLabel = uilabel(app.UIFigure);
            app.CottonGlovePageLabel.Text = 'Cotton Glove Page';
            app.BurnDetectionButton = uibutton(app.UIFigure, 'push');
            app.BurnDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BurnDetectionButtonPushed, true);
            app.BurnDetectionButton.Text = 'Burn Detection';
            app.StainsDetectionButton = uibutton(app.UIFigure, 'push');
            app.StainsDetectionButton.Text = 'Stains Detection';
            app.MissingFingerDetectionButton = uibutton(app.UIFigure, 'push');
            app.MissingFingerDetectionButton.Text = 'Missing Finger Detection';
            app.outputimage = uiimage(app.UIFigure);
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.uploadimage = uiimage(app.UIFigure);
            app.OutputImageLabel = uilabel(app.UIFigure);
            app.OutputImageLabel.Text = 'Output Image';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ClearImageButton = uibutton(app.UIFigure, 'push');
            app.ClearImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImageButtonPushed, true);
            app.ClearImageButton.Text = 'Clear Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cottonglovepage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end